classdef ROIprocess2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreprocessingOptionsPanel       matlab.ui.container.Panel
        SmoothingSwitch                 matlab.ui.control.Switch
        SmoothingSwitchLabel            matlab.ui.control.Label
        BaselineCorrectionSwitch        matlab.ui.control.Switch
        BaselineCorrectionSwitchLabel   matlab.ui.control.Label
        OutputPanel                     matlab.ui.container.Panel
        NumberofROIsGroup2EditField     matlab.ui.control.NumericEditField
        NumberofROIsGroup2Label         matlab.ui.control.Label
        NumberofROIsEditField           matlab.ui.control.EditField
        NumberofROIsEditFieldLabel      matlab.ui.control.Label
        AnalysisOptionsPanel            matlab.ui.container.Panel
        CompareGroupsSwitch             matlab.ui.control.ToggleSwitch
        AnalysisModeLabel               matlab.ui.control.Label
        MSModeSwitch                    matlab.ui.control.ToggleSwitch
        MSModeSwitchLabel               matlab.ui.control.Label
        BlankSubtractionSwitch          matlab.ui.control.ToggleSwitch
        BlankSubtractionLabel           matlab.ui.control.Label
        ReselectDataButton              matlab.ui.control.Button
        FilterOptionsPanel              matlab.ui.container.Panel
        ContaminantFilterSwitch         matlab.ui.control.Switch
        ContaminantFilterSwitchLabel    matlab.ui.control.Label
        AdductFilterSwitch              matlab.ui.control.Switch
        AdductFilterSwitchLabel         matlab.ui.control.Label
        IsotopeFilterSwitch             matlab.ui.control.Switch
        IsotopeFilterSwitchLabel        matlab.ui.control.Label
        ROIprocess2Label                matlab.ui.control.Label
        StartMainAnalysisButton         matlab.ui.control.Button
        SavitzkyGolaySmoothingParametersPanel  matlab.ui.container.Panel
        PolynomialDegreeEditField       matlab.ui.control.NumericEditField
        PolynomialDegreeEditFieldLabel  matlab.ui.control.Label
        FrameSizeEditField              matlab.ui.control.NumericEditField
        FrameSizeEditFieldLabel         matlab.ui.control.Label
        BaselineCorrectionParametersPanel  matlab.ui.container.Panel
        EstimationMethodDropDown        matlab.ui.control.DropDown
        EstimationMethodDropDownLabel   matlab.ui.control.Label
        QuantilValueEditField           matlab.ui.control.NumericEditField
        QuantilValueEditFieldLabel      matlab.ui.control.Label
        SmoothingMethodDropDown         matlab.ui.control.DropDown
        SmoothingMethodDropDownLabel    matlab.ui.control.Label
        RegressionMethodDropDown        matlab.ui.control.DropDown
        RegressionMethodDropDownLabel   matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        ShiftingWindowSizeEditField     matlab.ui.control.NumericEditField
        ShiftingWindowSizeEditFieldLabel  matlab.ui.control.Label
        ROIParametersPanel              matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        LastScanFile2EditField          matlab.ui.control.NumericEditField
        LastScanFile2EditFieldLabel     matlab.ui.control.Label
        FirstScanFile2EditField         matlab.ui.control.NumericEditField
        FirstScanFile2EditFieldLabel    matlab.ui.control.Label
        LastScanFile1EditField          matlab.ui.control.NumericEditField
        LastScanFile1Label              matlab.ui.control.Label
        FirstScanFile1EditField         matlab.ui.control.NumericEditField
        FirstScanFile1EditFieldLabel    matlab.ui.control.Label
        MinimumROIsizeEditField         matlab.ui.control.NumericEditField
        MinimumROIsizeEditFieldLabel    matlab.ui.control.Label
        MZErrorEditField                matlab.ui.control.NumericEditField
        MZErrorEditFieldLabel           matlab.ui.control.Label
        IntensityThresholdEditField     matlab.ui.control.NumericEditField
        IntensityThresholdEditFieldLabel  matlab.ui.control.Label
        ApplyChangesButton              matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        thresh = 5000; % ROI parameter Intensity Threshold
        mzerror = 0.05% ROI parameter mz window
        minroi = 20% ROI parameter minimum ROI size
        first1 = 1   % ROI parameter first scan to process
        last1 = 3000    % ROI parameter last scan to process
        first2 = 1   % ROI parameter first scan to process
        last2 = 3000    % ROI parameter last scan to process
        SWS = 200     % Baseline parameter Shifting Window Size
        SS = 200      % Baseline parameter Step Size
        Reg = 'pchip'     % Baseline parameter Regression Method
        Est = 'quantile'    % Baseline parameter Estimation Method
        Smoo = 'none'    % Baseline parameter Smoothing Method
        Quan = 0.1   % Baseline parameter Quantile Value
        Span = 15   % Smoothing parameter Frame Size
        Poly = 2    % Smoothing parameter poynomial degree
        BASE = false   % Swith toggle Baseline correction
        GOLAY = false   % Swith toggle Smoothing
        ISO = false    % Swith toggle Isotope filter
        ADD = false    % Swith toggle Addukt filter
        CONT = false   % Swith toggle Contaminant filter
        Peak = {}   % Loaded Intensities
        Time = {}   % Loaded Times
        Peak2 = {}   % Loaded Intensities
        Time2 = {}   % Loaded Times
        PeakB = {}   % Loaded Intensities
        TimeB = {}   % Loaded Times
        PeakB2 = {}   % Loaded Intensities
        TimeB2 = {}   % Loaded Times
        polarity = 'positive' % MS mode toggle
        MULTI = false % Analysis mode toggle
        BLK = false % Blank subtraction toggle
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = false;
            [File,path] = uigetfile('*.mzXML','Select Test Sample','MultiSelect','off');
            app.UIFigure.Visible = true;
            File=fullfile(path,File);
            msg=msgbox('MS Data is processing, stand by','Status','help');
            mzxml = mzxmlload(File,'Levels',1);
            [app.Peak, app.Time]= mzxml2peaks(mzxml);
            [nrows,~] = size(app.Peak);
            app.LastScanFile1EditField.Value = nrows;
            app.last1 = nrows;
                for j = 1:nrows
                    data=app.Peak{j,1};
                    idx=data(:,2)< max(data(:,2))*0.001;
                    data(idx,:)=[];
                    app.Peak{j,1}=data;
                end
            BPC = NaN * ones(nrows,1);
            for f=1:nrows
                BPC(f)=max(app.Peak{f,1}(1:end,2));
            end
            plot(app.UIAxes,BPC)
            close(msg)
        end

        % Value changed function: IntensityThresholdEditField
        function IntensityThresholdEditFieldValueChanged(app, event)
            app.thresh = app.IntensityThresholdEditField.Value;
            
        end

        % Value changed function: MZErrorEditField
        function MZErrorEditFieldValueChanged(app, event)
            app.mzerror = app.MZErrorEditField.Value;
            
        end

        % Value changed function: MinimumROIsizeEditField
        function MinimumROIsizeEditFieldValueChanged(app, event)
            app.minroi = app.MinimumROIsizeEditField.Value;
            
        end

        % Value changed function: ShiftingWindowSizeEditField
        function ShiftingWindowSizeEditFieldValueChanged(app, event)
            app.SWS = app.ShiftingWindowSizeEditField.Value;
            
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            app.SS = app.StepSizeEditField.Value;
            
        end

        % Value changed function: RegressionMethodDropDown
        function RegressionMethodDropDownValueChanged(app, event)
            app.Reg = app.RegressionMethodDropDown.Value;
            
        end

        % Value changed function: EstimationMethodDropDown
        function EstimationMethodDropDownValueChanged(app, event)
            app.Est = app.EstimationMethodDropDown.Value;
            
        end

        % Value changed function: SmoothingMethodDropDown
        function SmoothingMethodDropDownValueChanged(app, event)
            app.Smoo = app.SmoothingMethodDropDown.Value;
            
        end

        % Value changed function: QuantilValueEditField
        function QuantilValueEditFieldValueChanged(app, event)
            app.Quan = app.QuantilValueEditField.Value;
            
        end

        % Value changed function: FrameSizeEditField
        function FrameSizeEditFieldValueChanged(app, event)
            app.Span = app.FrameSizeEditField.Value;
            
        end

        % Value changed function: PolynomialDegreeEditField
        function PolynomialDegreeEditFieldValueChanged(app, event)
            app.Poly = app.PolynomialDegreeEditField.Value;
            
        end

        % Value changed function: BaselineCorrectionSwitch
        function BaselineCorrectionSwitchValueChanged(app, event)
            app.BASE = app.BaselineCorrectionSwitch.Value;
            
        end

        % Value changed function: SmoothingSwitch
        function SmoothingSwitchValueChanged(app, event)
            app.GOLAY = app.SmoothingSwitch.Value;
            
        end

        % Value changed function: IsotopeFilterSwitch
        function IsotopeFilterSwitchValueChanged(app, event)
            app.ISO = app.IsotopeFilterSwitch.Value;
            
        end

        % Value changed function: AdductFilterSwitch
        function AdductFilterSwitchValueChanged(app, event)
            app.ADD = app.AdductFilterSwitch.Value;
            
        end

        % Value changed function: ContaminantFilterSwitch
        function ContaminantFilterSwitchValueChanged(app, event)
            app.CONT = app.ContaminantFilterSwitch.Value;
            
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
        TestPeak=app.Peak(app.first1:app.last1,:);
        TestTimes=app.Time(app.first1:app.last1,:);
        nrows=length(TestTimes);
        [mzroi,MSroi]=ROIpeaksApp(TestPeak,app.thresh,app.mzerror,app.minroi,nrows,TestTimes);
        if app.BLK == true
            TestPeakB=app.PeakB(app.first1:app.last1,:);
            TestTimesB=app.TimeB(app.first1:app.last1,:);
            [mzroiB,MSroiB] = ROIpeaksApp(TestPeakB,app.thresh,app.mzerror,app.minroi,nrows,TestTimesB);
            [MSroi,mzroi,~] = MSroiaugApp(MSroi,MSroiB,mzroi,mzroiB,app.mzerror,app.thresh,TestTimes,TestTimesB);
            MSroiBLK = MSroi(nrows+1:end,:);
            MSroi(nrows+1:end,:)= [];
            MSroi = MSroi-MSroiBLK;
            MSroi=max(MSroi,0);
        end
        if app.MULTI == true
            TestPeak2=app.Peak2(app.first2:app.last2,:);
            TestTimes2=app.Time2(app.first2:app.last2,:);
            nrows2=length(TestTimes2);
            [mzroi2,MSroi2]=ROIpeaksApp(TestPeak2,app.thresh,app.mzerror,app.minroi,nrows2,TestTimes2); 
            app.UIAxes.Title.String = "Chromatogram of ROIs Group 1";
            app.UIAxes_3.Title.String = "Chromatogram of ROIs Group 2";
            if app.BLK == true
                TestPeakB2=app.PeakB2(app.first2:app.last2,:);
                TestTimesB2=app.TimeB2(app.first2:app.last2,:);
                [mzroiB2,MSroiB2] = ROIpeaksApp(TestPeakB2,app.thresh,app.mzerror,app.minroi,nrows2,TestTimesB2);
                [MSroi2,mzroi2,~] = MSroiaugApp(MSroi2,MSroiB2,mzroi2,mzroiB2,app.mzerror,app.thresh,TestTimes2,TestTimesB2);
                MSroiBLK2 = MSroi2(nrows+1:end,:);
                MSroi2(nrows+1:end,:)= [];
                MSroi2 = MSroi2-MSroiBLK2;
                MSroi2=max(MSroi2,0);
            end
        else
            app.UIAxes.Title.String = "Chromatogram of ROIs";
        end

        if app.BASE == true %BaselineCorrection
            MSroi = msbackadj(TestTimes,MSroi,'WindowSize',app.SWS,'StepSize',app.SS,'RegressionMethod',app.Reg,'EstimationMethod',app.Est,'SmoothMethod',app.Smoo,'QuantileValue',app.Quan,'PreserveHeights',true);
            MSroi(isnan(MSroi))=0; %Remove possible NaN values
            MSroi=max(MSroi,0); %Force negative Intensities to 0
            if app.MULTI == true
                MSroi2 = msbackadj(TestTimes2,MSroi2,'WindowSize',app.SWS,'StepSize',app.SS,'RegressionMethod',app.Reg,'EstimationMethod',app.Est,'SmoothMethod',app.Smoo,'QuantileValue',app.Quan,'PreserveHeights',true);
                MSroi2(isnan(MSroi2))=0; %Remove possible NaN values
                MSroi2=max(MSroi2,0); %Force negative Intensities to 0
            end
        end
        if app.GOLAY == true % Smoothing
            MSroi = mssgolay(TestTimes,MSroi,'Span',app.Span,'Degree',app.Poly);
            MSroi(isnan(MSroi))=0; %Remove possible NaN values
            MSroi=max(MSroi,0); %Force negative Intensities to 0
            if app.MULTI == true
                MSroi2 = mssgolay(TestTimes2,MSroi2,'Span',app.Span,'Degree',app.Poly);
                MSroi2(isnan(MSroi2))=0; %Remove possible NaN values
                MSroi2=max(MSroi2,0); %Force negative Intensities to 0
            end
        end
        if app.ISO == true
            Isotopes=readtable('UWPR_CommonMassSpecContaminants.xls','Sheet',"Isotopes");
            [MSroi,~]=ApplyFilterApp(MSroi,mzroi,app.mzerror,app.thresh,Isotopes);
            if app.MULTI == true
                [MSroi2,~]=ApplyFilterApp(MSroi2,mzroi2,app.mzerror,app.thresh,Isotopes);
            end
        end
        if app.ADD == true
                AdductList=readtable('UWPR_CommonMassSpecContaminants.xls','Sheet',"Adducts");
                [MSroi,~]=ApplyFilterApp(MSroi,mzroi,app.mzerror,app.thresh,AdductList);
                if app.MULTI == true
                    [MSroi2,~]=ApplyFilterApp(MSroi2,mzroi2,app.mzerror,app.thresh,AdductList);
                end
        end
        if app.CONT == true
            [MSroi,mzroi]=filterContaminantsApp(MSroi,mzroi,app.mzerror,app.polarity);
            if app.MULTI == true
                [MSroi2,mzroi2]=filterContaminantsApp(MSroi2,mzroi2,app.mzerror,app.polarity);
            end
        end
        mzInt=sum(MSroi);
        mzInt=rescale(mzInt);
        plot(app.UIAxes,MSroi)
        stem(app.UIAxes_2,mzroi,mzInt,'Marker','none','Color','k')
        if app.MULTI == true
            mzInt2=sum(MSroi2);
            mzInt2=rescale(mzInt2);
            plot(app.UIAxes_3,MSroi2)
            stem(app.UIAxes_4,mzroi2,mzInt2,'Marker','none','Color','k')
            app.NumberofROIsGroup2EditField.Value = length(mzroi2);
        end
        mzroi = num2str(length(mzroi));
        app.NumberofROIsEditField.Value = mzroi;
        end

        % Button pushed function: ReselectDataButton
        function ReselectDataButtonPushed(app, event)
            % Select Data
            [File,path] = uigetfile('*.mzXML','Select Test Sample','MultiSelect','off');
            SelectedFiles{1,1}=fullfile(path,File);
            if app.MULTI == true
                [File,path] = uigetfile('*.mzXML','Select Test Sample For Group 2','MultiSelect','off');
                SelectedFiles{1,2}=fullfile(path,File);
            end
            if app.BLK == true
                [File,path] = uigetfile('*.mzXML','Select Blank Sample','MultiSelect','off');
                SelectedFiles{1,3}=fullfile(path,File);
            end
            if app.BLK == true && app.MULTI == true
                [File,path] = uigetfile('*.mzXML','Select Blank Sample for Group 2','MultiSelect','off');
                SelectedFiles{1,4}=fullfile(path,File);
            end
            msg=msgbox('MS Data is processing, stand by','Status','help');
            parfor k=1:length(SelectedFiles)
                mzxml{1,k} = mzxmlload(SelectedFiles{1,k},'Levels',1); %Load Files
                [Scans{1,k}, ScanTimes{1,k}]= mzxml2peaks(mzxml{1,k});
            end
            app.Peak=Scans{1,1};
            app.Time = ScanTimes{1,1};
            [nrows,~] = size(app.Peak);
            app.FirstScanFile1EditField.Value = 1;
            app.LastScanFile1EditField.Value = nrows;
            app.last1 = nrows;
                for j = 1:nrows %File 1 Cleanup
                    data=app.Peak{j,1};
                    idx=data(:,2)< max(data(:,2))*0.001;
                    data(idx,:)=[];
                    app.Peak{j,1}=data;
                end
            BPC = NaN * ones(nrows,1); %File 1 BPC calculation
            for f=1:nrows
                BPC(f)=max(app.Peak{f,1}(1:end,2));
            end

            if app.MULTI == true
                app.Peak2 = Scans{1,2};
                app.Time2 = ScanTimes{1,2};
                [nrows2,~] = size(app.Peak2);
                app.FirstScanFile2EditField.Value = 1;
                app.LastScanFile2EditField.Value = nrows2;
                app.last2 = nrows2;
                for j = 1:nrows2 % File 2 Cleanup
                    data=app.Peak2{j,1};
                    idx=data(:,2)< max(data(:,2))*0.001;
                    data(idx,:)=[];
                    app.Peak2{j,1}=data;
                end
                    BPC2 = NaN * ones(nrows2,1); % File 2 BPC calculation
                for f=1:nrows2
                    BPC2(f)=max(app.Peak2{f,1}(1:end,2));
                end
            end
            if app.BLK == true
                app.PeakB = Scans{1,3};
                app.TimeB = ScanTimes{1,3};
                [nrowsB,~] = size(app.PeakB);
                for j = 1:nrowsB % File 2 Cleanup
                    data=app.PeakB{j,1};
                    idx=data(:,2)< max(data(:,2))*0.001;
                    data(idx,:)=[];
                    app.PeakB{j,1}=data;
                end
            end
            if app.BLK == true && app.MULTI == true
                app.PeakB2 = Scans{1,4};
                app.TimeB2 = ScanTimes{1,4};
                [nrowsB2,~] = size(app.PeakB2);
                for j = 1:nrowsB2 % File 2 Cleanup
                    data=app.PeakB2{j,1};
                    idx=data(:,2)< max(data(:,2))*0.001;
                    data(idx,:)=[];
                    app.PeakB2{j,1}=data;
                end
            end
            if app.MULTI == false % Plot for Single analysis
                app.UIAxes.Title.String = "Base Peak Chromatogram";
                plot(app.UIAxes,BPC)
            else % Plots for Multi analysis
                app.UIAxes.Title.String = "Base Peak Chromatogram Group 1";
                app.UIAxes_3.Title.String = "Base Peak Chromatogram Group 2";
                plot(app.UIAxes,BPC)
                plot(app.UIAxes_3,BPC2)
            end
            close(msg)
        end

        % Value changed function: FirstScanFile1EditField
        function FirstScanFile1EditFieldValueChanged(app, event)
            app.first1 = app.FirstScanFile1EditField.Value;
            
        end

        % Value changed function: LastScanFile1EditField
        function LastScanFile1EditFieldValueChanged(app, event)
            app.last1 = app.LastScanFile1EditField.Value;
            
        end

        % Value changed function: MSModeSwitch
        function MSModeKnobValueChanged(app, event)
            app.polarity = app.MSModeSwitch.Value;
            
        end

        % Value changed function: CompareGroupsSwitch
        function CompareGroupsSwitchValueChanged(app, event)
            app.MULTI = app.CompareGroupsSwitch.Value;
            app.MULTI = convertCharsToStrings(app.MULTI);
            app.MULTI = app.MULTI == "true";
            if  isempty(app.Peak2) == true && app.MULTI == true
                [File,path] = uigetfile('*.mzXML','Select Test Sample for Group 2','MultiSelect','off');
                File=fullfile(path,File);
                msg=msgbox('MS Data is processing, stand by','Status','help');
                mzxml = mzxmlload(File,'Levels',1);
                [app.Peak2, app.Time2]= mzxml2peaks(mzxml);
                [nrows2,~] = size(app.Peak2);
                app.LastScanFile2EditField.Value = nrows2;
                for j = 1:nrows2
                    data=app.Peak2{j,1};
                    idx=data(:,2)< max(data(:,2))*0.001;
                    data(idx,:)=[];
                    app.Peak2{j,1}=data;
                end
                BPC = NaN * ones(nrows2,1);
                for f=1:nrows2
                    BPC(f)=max(app.Peak2{f,1}(1:end,2));
                end
                app.UIAxes_3.Visible = true;
                plot(app.UIAxes_3,BPC)
                close(msg)
            end
            if app.MULTI == true %resize window and enable multi analysis specific fields
                app.UIFigure.Position = [100 100 1780 921];
                app.UIAxes_3.Visible = true;
                app.UIAxes_4.Visible = true;
                app.NumberofROIsEditFieldLabel.Text = 'Number of ROIs Group 1';
                app.FirstScanFile2EditFieldLabel.Visible = true;
                app.LastScanFile2EditFieldLabel.Visible = true;
                app.FirstScanFile2EditField.Visible = true;
                app.LastScanFile2EditField.Visible = true;
                app.NumberofROIsGroup2EditField.Visible = true;
                app.NumberofROIsGroup2Label.Visible = true;
            else %resize window and disable multi analysis specific fields
                app.UIFigure.Position = [100 100 1150 921];
                app.UIAxes_3.Visible = false;
                app.UIAxes_4.Visible = false;
                app.NumberofROIsEditFieldLabel.Text = 'Number of ROIs';
                app.FirstScanFile2EditFieldLabel.Visible = false;
                app.LastScanFile2EditFieldLabel.Visible = false;
                app.FirstScanFile2EditField.Visible = false;
                app.LastScanFile2EditField.Visible = false;
                app.NumberofROIsGroup2EditField.Visible = false;
                app.NumberofROIsGroup2Label.Visible = false;
            end
        end

        % Value changed function: FirstScanFile2EditField
        function FirstScanFile2EditFieldValueChanged(app, event)
            app.first2 = app.FirstScanFile2EditField.Value;
            
        end

        % Value changed function: BlankSubtractionSwitch
        function BlankSubtractionSwitchValueChanged(app, event)
            app.BLK = app.BlankSubtractionSwitch.Value;
            app.BLK = convertCharsToStrings(app.BLK);
            app.BLK = app.BLK == "true";
            if  isempty(app.PeakB) == true && app.BLK == true && app.MULTI == false
                [FileB,path] = uigetfile('*.mzXML','Select Blank Sample','MultiSelect','off');
                SelectedBLKs=fullfile(path,FileB);
                msg=msgbox('MS Data is processing, stand by','Status','help');
                mzxml = mzxmlload(SelectedBLKs,'Levels',1);
                [app.PeakB, app.TimeB]= mzxml2peaks(mzxml);
                [nrowsB,~] = size(app.PeakB);
                app.LastScanFile2EditField.Value = nrowsB;
                for j = 1:nrowsB
                    data=app.PeakB{j,1};
                    idx=data(:,2)< max(data(:,2))*0.001;
                    data(idx,:)=[];
                    app.PeakB{j,1}=data;
                end
            end
                if  isempty(app.PeakB2) == true && app.BLK == true && app.MULTI == true
                [FileB,path] = uigetfile('*.mzXML','Select Blank Sample for Group 1','MultiSelect','off');
                SelectedBLKs{1,1}=fullfile(path,FileB);
                [FileB2,path] = uigetfile('*.mzXML','Select Blank Sample for Group 1','MultiSelect','off');
                SelectedBLKs{2,1}=fullfile(path,FileB2);                
                msg=msgbox('MS Data is processing, stand by','Status','help');
                parfor k=1:2
                    mzxml{1,k} = mzxmlload(SelectedBLKs{1,k},'Levels',1);
                    [BLKs{1,k}, TimeBLK{1,k}]= mzxml2peaks(mzxml{1,k});
                end
                app.PeakB=BLKs{1,1};
                app.PeakB2=BLKs{1,2};
                app.TimeB=TimeBLK{1,1};
                app.TimeB2=TimeBLK{1,2};                
                    [nrowsB,~] = size(app.PeakB);
                    [nrowsB2,~] = size(app.PeakB2);
                for j = 1:nrowsB
                    data=app.PeakB{j,1};
                    idx=data(:,2)< max(data(:,2))*0.001;
                    data(idx,:)=[];
                    app.PeakB{j,1}=data;
                end
                for j = 1:nrowsB2
                    data=app.PeakB2{j,1};
                    idx=data(:,2)< max(data(:,2))*0.001;
                    data(idx,:)=[];
                    app.PeakB2{j,1}=data;
                end
                end
                close(msg)
        end

        % Value changed function: LastScanFile2EditField
        function LastScanFile2EditFieldValueChanged(app, event)
            app.last2 = app.LastScanFile2EditField.Value;
            
        end

        % Button pushed function: StartMainAnalysisButton
        function StartMainAnalysisButtonPushed(app, event)
            [MSroi_end,mzroi_end,time_end,nRows,Parameters] = ROIproApp(app);
            assignin("base","MSroi_end",MSroi_end)
            assignin("base","mzroi_end",mzroi_end)
            assignin("base","time_end",time_end)
            assignin("base","nRows",nRows)
            assignin("base","Parameters",Parameters)
            app.first1 = app.FirstScanFile1EditField.Value;
            app.first2 = app.FirstScanFile2EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Base Peak Chromatogram')
            xlabel(app.UIAxes, 'Scan Number')
            ylabel(app.UIAxes, 'Intensity [arb]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'plot1';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'ROI m/z')
            xlabel(app.UIAxes_2, 'm/z [Da]')
            ylabel(app.UIAxes_2, 'Intensity normalized')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Base Peak Chromatogram Group 2')
            xlabel(app.UIAxes_3, 'Scan Number')
            ylabel(app.UIAxes_3, 'Intensity [arb]')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'ROI m/z Group 2')
            xlabel(app.UIAxes_4, 'm/z [Da]')
            ylabel(app.UIAxes_4, 'Intensity Normalized ')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.Visible = 'off';
            app.ApplyChangesButton = uibutton(app.UIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Tooltip = {'Apply the selected Options and settings and update graphs.'};
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.ROIParametersPanel = uipanel(app.UIFigure);
            app.ROIParametersPanel.Title = 'ROI Parameters';
            app.GridLayout = uigridlayout(app.ROIParametersPanel);
            app.IntensityThresholdEditFieldLabel = uilabel(app.GridLayout);
            app.IntensityThresholdEditFieldLabel.Text = 'Intensity Threshold';
            app.IntensityThresholdEditField = uieditfield(app.GridLayout, 'numeric');
            app.IntensityThresholdEditField.Limits = [1 Inf];
            app.IntensityThresholdEditField.ValueDisplayFormat = '%.0f';
            app.IntensityThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @IntensityThresholdEditFieldValueChanged, true);
            app.IntensityThresholdEditField.Tooltip = {'Minimum Intensity threshold for ROI'};
            app.IntensityThresholdEditField.Value = 5000;
            app.MZErrorEditFieldLabel = uilabel(app.GridLayout);
            app.MZErrorEditFieldLabel.Text = 'MZ Error';
            app.MZErrorEditField = uieditfield(app.GridLayout, 'numeric');
            app.MZErrorEditField.Limits = [0 Inf];
            app.MZErrorEditField.ValueChangedFcn = createCallbackFcn(app, @MZErrorEditFieldValueChanged, true);
            app.MZErrorEditField.Tooltip = {'Mass window in which m/z values are considered to be the same ROI'};
            app.MZErrorEditField.Value = 0.05;
            app.MinimumROIsizeEditFieldLabel = uilabel(app.GridLayout);
            app.MinimumROIsizeEditFieldLabel.Text = 'Minimum ROI size';
            app.MinimumROIsizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinimumROIsizeEditField.Limits = [1 Inf];
            app.MinimumROIsizeEditField.ValueDisplayFormat = '%.0f';
            app.MinimumROIsizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumROIsizeEditFieldValueChanged, true);
            app.MinimumROIsizeEditField.Tooltip = {'Minimum number of occurrences necessary to be an ROI'};
            app.MinimumROIsizeEditField.Value = 20;
            app.FirstScanFile1EditFieldLabel = uilabel(app.GridLayout);
            app.FirstScanFile1EditFieldLabel.Text = 'First Scan File 1';
            app.FirstScanFile1EditField = uieditfield(app.GridLayout, 'numeric');
            app.FirstScanFile1EditField.Limits = [1 Inf];
            app.FirstScanFile1EditField.ValueDisplayFormat = '%.0f';
            app.FirstScanFile1EditField.ValueChangedFcn = createCallbackFcn(app, @FirstScanFile1EditFieldValueChanged, true);
            app.FirstScanFile1EditField.Value = 1;
            app.LastScanFile1Label = uilabel(app.GridLayout);
            app.LastScanFile1Label.Text = 'Last Scan File 1';
            app.LastScanFile1EditField = uieditfield(app.GridLayout, 'numeric');
            app.LastScanFile1EditField.Limits = [2 Inf];
            app.LastScanFile1EditField.ValueDisplayFormat = '%.0f';
            app.LastScanFile1EditField.ValueChangedFcn = createCallbackFcn(app, @LastScanFile1EditFieldValueChanged, true);
            app.LastScanFile1EditField.Value = 3000;
            app.FirstScanFile2EditFieldLabel = uilabel(app.GridLayout);
            app.FirstScanFile2EditFieldLabel.Visible = 'off';
            app.FirstScanFile2EditFieldLabel.Text = 'First Scan File 2';
            app.FirstScanFile2EditField = uieditfield(app.GridLayout, 'numeric');
            app.FirstScanFile2EditField.Limits = [1 Inf];
            app.FirstScanFile2EditField.ValueDisplayFormat = '%.0f';
            app.FirstScanFile2EditField.ValueChangedFcn = createCallbackFcn(app, @FirstScanFile2EditFieldValueChanged, true);
            app.FirstScanFile2EditField.Visible = 'off';
            app.FirstScanFile2EditField.Value = 1;
            app.LastScanFile2EditFieldLabel = uilabel(app.GridLayout);
            app.LastScanFile2EditFieldLabel.Visible = 'off';
            app.LastScanFile2EditFieldLabel.Text = 'Last Scan File 2';
            app.LastScanFile2EditField = uieditfield(app.GridLayout, 'numeric');
            app.LastScanFile2EditField.Limits = [2 Inf];
            app.LastScanFile2EditField.ValueDisplayFormat = '%.0f';
            app.LastScanFile2EditField.ValueChangedFcn = createCallbackFcn(app, @LastScanFile2EditFieldValueChanged, true);
            app.LastScanFile2EditField.Visible = 'off';
            app.LastScanFile2EditField.Value = 3000;
            app.BaselineCorrectionParametersPanel = uipanel(app.UIFigure);
            app.BaselineCorrectionParametersPanel.Title = 'Baseline Correction Parameters';
            app.ShiftingWindowSizeEditFieldLabel = uilabel(app.BaselineCorrectionParametersPanel);
            app.ShiftingWindowSizeEditFieldLabel.Text = 'Shifting Window Size';
            app.ShiftingWindowSizeEditField = uieditfield(app.BaselineCorrectionParametersPanel, 'numeric');
            app.ShiftingWindowSizeEditField.Limits = [1 Inf];
            app.ShiftingWindowSizeEditField.ValueDisplayFormat = '%.0f';
            app.ShiftingWindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @ShiftingWindowSizeEditFieldValueChanged, true);
            app.ShiftingWindowSizeEditField.Tooltip = {'Window size for baseline estimation'};
            app.ShiftingWindowSizeEditField.Value = 200;
            app.StepSizeEditFieldLabel = uilabel(app.BaselineCorrectionParametersPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.BaselineCorrectionParametersPanel, 'numeric');
            app.StepSizeEditField.Limits = [1 Inf];
            app.StepSizeEditField.ValueDisplayFormat = '%.0f';
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StepSizeEditField.Tooltip = {'Defines placement of baseline estimation windows every X scans.'};
            app.StepSizeEditField.Value = 200;
            app.RegressionMethodDropDownLabel = uilabel(app.BaselineCorrectionParametersPanel);
            app.RegressionMethodDropDownLabel.Text = 'Regression Method';
            app.RegressionMethodDropDown = uidropdown(app.BaselineCorrectionParametersPanel);
            app.RegressionMethodDropDown.Items = {'Shape-preserving piecewise cubic interpolation', 'Linear Interpolation', 'Spline Interpolation'};
            app.RegressionMethodDropDown.ItemsData = {'pchip', 'linear', 'spline'};
            app.RegressionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @RegressionMethodDropDownValueChanged, true);
            app.RegressionMethodDropDown.Tooltip = {'Method to regress the window estimated points to a soft curve, specified as one of the following:'; 'pchip - Shape-preserving piecewise cubic interpolation.'; 'linear - Linear interpolation'; 'spline - Spline interpolation'};
            app.RegressionMethodDropDown.Value = 'pchip';
            app.SmoothingMethodDropDownLabel = uilabel(app.BaselineCorrectionParametersPanel);
            app.SmoothingMethodDropDownLabel.Text = 'Smoothing Method';
            app.SmoothingMethodDropDown = uidropdown(app.BaselineCorrectionParametersPanel);
            app.SmoothingMethodDropDown.Items = {'none', 'Linear fit', 'Quadratic fit', 'Robust linear fit', 'Robust quadratic fit'};
            app.SmoothingMethodDropDown.ItemsData = {'none', 'lowess', 'loess', 'rlowess', 'rloess'};
            app.SmoothingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothingMethodDropDownValueChanged, true);
            app.SmoothingMethodDropDown.Tooltip = {'Method to smooth the curve of estimated points'};
            app.SmoothingMethodDropDown.Value = 'none';
            app.QuantilValueEditFieldLabel = uilabel(app.BaselineCorrectionParametersPanel);
            app.QuantilValueEditFieldLabel.Text = 'Quantil Value';
            app.QuantilValueEditField = uieditfield(app.BaselineCorrectionParametersPanel, 'numeric');
            app.QuantilValueEditField.Limits = [0 1];
            app.QuantilValueEditField.ValueChangedFcn = createCallbackFcn(app, @QuantilValueEditFieldValueChanged, true);
            app.QuantilValueEditField.Tooltip = {'Quantile value for quantil method.'};
            app.QuantilValueEditField.Value = 0.1;
            app.EstimationMethodDropDownLabel = uilabel(app.BaselineCorrectionParametersPanel);
            app.EstimationMethodDropDownLabel.Text = 'Estimation Method';
            app.EstimationMethodDropDown = uidropdown(app.BaselineCorrectionParametersPanel);
            app.EstimationMethodDropDown.Items = {'Quantile', 'Estimation-Maximization'};
            app.EstimationMethodDropDown.ItemsData = {'quantile', 'em'};
            app.EstimationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @EstimationMethodDropDownValueChanged, true);
            app.EstimationMethodDropDown.Value = 'quantile';
            app.SavitzkyGolaySmoothingParametersPanel = uipanel(app.UIFigure);
            app.SavitzkyGolaySmoothingParametersPanel.Title = 'Savitzky-Golay Smoothing Parameters';
            app.FrameSizeEditFieldLabel = uilabel(app.SavitzkyGolaySmoothingParametersPanel);
            app.FrameSizeEditFieldLabel.Text = 'Frame Size';
            app.FrameSizeEditField = uieditfield(app.SavitzkyGolaySmoothingParametersPanel, 'numeric');
            app.FrameSizeEditField.Limits = [1 Inf];
            app.FrameSizeEditField.ValueDisplayFormat = '%.0f';
            app.FrameSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FrameSizeEditFieldValueChanged, true);
            app.FrameSizeEditField.Tooltip = {'Moving Frame Size. Higher values smooth the signal more with an increase in computation time'};
            app.FrameSizeEditField.Value = 15;
            app.PolynomialDegreeEditFieldLabel = uilabel(app.SavitzkyGolaySmoothingParametersPanel);
            app.PolynomialDegreeEditFieldLabel.Text = 'Polynomial Degree';
            app.PolynomialDegreeEditField = uieditfield(app.SavitzkyGolaySmoothingParametersPanel, 'numeric');
            app.PolynomialDegreeEditField.Limits = [1 Inf];
            app.PolynomialDegreeEditField.ValueDisplayFormat = '%.0f';
            app.PolynomialDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @PolynomialDegreeEditFieldValueChanged, true);
            app.PolynomialDegreeEditField.Tooltip = {'Specifies the degree of the polynomial fitted to the points in the moving frame'};
            app.PolynomialDegreeEditField.Value = 2;
            app.StartMainAnalysisButton = uibutton(app.UIFigure, 'push');
            app.StartMainAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartMainAnalysisButtonPushed, true);
            app.StartMainAnalysisButton.Tooltip = {'Starts main analysis. The same selected options and settings will be used. You only have to select files to process.'};
            app.StartMainAnalysisButton.Text = 'Start Main Analysis';
            app.ROIprocess2Label = uilabel(app.UIFigure);
            app.ROIprocess2Label.Text = 'ROIprocess 2';
            app.FilterOptionsPanel = uipanel(app.UIFigure);
            app.FilterOptionsPanel.Title = 'Filter Options';
            app.IsotopeFilterSwitchLabel = uilabel(app.FilterOptionsPanel);
            app.IsotopeFilterSwitchLabel.Text = 'Isotope Filter';
            app.IsotopeFilterSwitch = uiswitch(app.FilterOptionsPanel, 'slider');
            app.IsotopeFilterSwitch.ItemsData = [false true];
            app.IsotopeFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @IsotopeFilterSwitchValueChanged, true);
            app.IsotopeFilterSwitch.Value = false;
            app.AdductFilterSwitchLabel = uilabel(app.FilterOptionsPanel);
            app.AdductFilterSwitchLabel.Text = 'Adduct Filter';
            app.AdductFilterSwitch = uiswitch(app.FilterOptionsPanel, 'slider');
            app.AdductFilterSwitch.ItemsData = [false true];
            app.AdductFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @AdductFilterSwitchValueChanged, true);
            app.AdductFilterSwitch.Value = false;
            app.ContaminantFilterSwitchLabel = uilabel(app.FilterOptionsPanel);
            app.ContaminantFilterSwitchLabel.Text = 'Contaminant Filter';
            app.ContaminantFilterSwitch = uiswitch(app.FilterOptionsPanel, 'slider');
            app.ContaminantFilterSwitch.ItemsData = [false true];
            app.ContaminantFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @ContaminantFilterSwitchValueChanged, true);
            app.ContaminantFilterSwitch.Value = false;
            app.ReselectDataButton = uibutton(app.UIFigure, 'push');
            app.ReselectDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReselectDataButtonPushed, true);
            app.ReselectDataButton.Text = 'Reselect Data';
            app.AnalysisOptionsPanel = uipanel(app.UIFigure);
            app.AnalysisOptionsPanel.Title = 'Analysis Options';
            app.BlankSubtractionLabel = uilabel(app.AnalysisOptionsPanel);
            app.BlankSubtractionLabel.Text = {'Blank '; 'Subtraction'};
            app.BlankSubtractionSwitch = uiswitch(app.AnalysisOptionsPanel, 'toggle');
            app.BlankSubtractionSwitch.ItemsData = {'false', 'true'};
            app.BlankSubtractionSwitch.ValueChangedFcn = createCallbackFcn(app, @BlankSubtractionSwitchValueChanged, true);
            app.BlankSubtractionSwitch.Value = 'false';
            app.MSModeSwitchLabel = uilabel(app.AnalysisOptionsPanel);
            app.MSModeSwitchLabel.Text = 'MS Mode';
            app.MSModeSwitch = uiswitch(app.AnalysisOptionsPanel, 'toggle');
            app.MSModeSwitch.Items = {'Positive', 'Negative'};
            app.MSModeSwitch.ItemsData = {'positive', 'negative'};
            app.MSModeSwitch.ValueChangedFcn = createCallbackFcn(app, @MSModeKnobValueChanged, true);
            app.MSModeSwitch.Value = 'positive';
            app.AnalysisModeLabel = uilabel(app.AnalysisOptionsPanel);
            app.AnalysisModeLabel.Text = {'Compare'; 'Groups'};
            app.CompareGroupsSwitch = uiswitch(app.AnalysisOptionsPanel, 'toggle');
            app.CompareGroupsSwitch.ItemsData = {'false', 'true'};
            app.CompareGroupsSwitch.ValueChangedFcn = createCallbackFcn(app, @CompareGroupsSwitchValueChanged, true);
            app.CompareGroupsSwitch.Value = 'false';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.NumberofROIsEditFieldLabel = uilabel(app.OutputPanel);
            app.NumberofROIsEditFieldLabel.Text = 'Number of ROIs';
            app.NumberofROIsEditField = uieditfield(app.OutputPanel, 'text');
            app.NumberofROIsEditField.Editable = 'off';
            app.NumberofROIsEditField.Value = '0';
            app.NumberofROIsGroup2Label = uilabel(app.OutputPanel);
            app.NumberofROIsGroup2Label.Visible = 'off';
            app.NumberofROIsGroup2Label.Text = 'Number of ROIs Group 2';
            app.NumberofROIsGroup2EditField = uieditfield(app.OutputPanel, 'numeric');
            app.NumberofROIsGroup2EditField.Editable = 'off';
            app.NumberofROIsGroup2EditField.Visible = 'off';
            app.PreprocessingOptionsPanel = uipanel(app.UIFigure);
            app.PreprocessingOptionsPanel.Title = 'Preprocessing Options';
            app.BaselineCorrectionSwitchLabel = uilabel(app.PreprocessingOptionsPanel);
            app.BaselineCorrectionSwitchLabel.Text = 'Baseline Correction';
            app.BaselineCorrectionSwitch = uiswitch(app.PreprocessingOptionsPanel, 'slider');
            app.BaselineCorrectionSwitch.ItemsData = [false true];
            app.BaselineCorrectionSwitch.ValueChangedFcn = createCallbackFcn(app, @BaselineCorrectionSwitchValueChanged, true);
            app.BaselineCorrectionSwitch.Value = false;
            app.SmoothingSwitchLabel = uilabel(app.PreprocessingOptionsPanel);
            app.SmoothingSwitchLabel.Text = 'Smoothing';
            app.SmoothingSwitch = uiswitch(app.PreprocessingOptionsPanel, 'slider');
            app.SmoothingSwitch.ItemsData = [false true];
            app.SmoothingSwitch.ValueChangedFcn = createCallbackFcn(app, @SmoothingSwitchValueChanged, true);
            app.SmoothingSwitch.Value = false;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIprocess2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end